classdef NicheSVM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLamp_2                    matlab.ui.control.Lamp
        StatusLamp_2Label               matlab.ui.control.Label
        AdavancevisualanalysisPanel     matlab.ui.container.Panel
        SpotsizeEditField               matlab.ui.control.NumericEditField
        SpotsizeEditFieldLabel          matlab.ui.control.Label
        cpathLabel                      matlab.ui.control.Label
        CurrentpathLabel                matlab.ui.control.Label
        DEGcelltypeexpressionPlotButton  matlab.ui.control.Button
        DEGaverageexpressionplotButton  matlab.ui.control.Button
        NeighborspecificgeneplotButton  matlab.ui.control.Button
        SetButton_2                     matlab.ui.control.Button
        PathEditField_2                 matlab.ui.control.EditField
        PathEditField_2Label            matlab.ui.control.Label
        AnalysisPanel                   matlab.ui.container.Panel
        StartAnalysisButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ClustersEditField               matlab.ui.control.EditField
        ClustersEditFieldLabel          matlab.ui.control.Label
        lrCuttoffEditField              matlab.ui.control.NumericEditField
        lrCuttoffEditFieldLabel         matlab.ui.control.Label
        pCutoffEditField                matlab.ui.control.NumericEditField
        pCutoffEditFieldLabel           matlab.ui.control.Label
        ExistCuttoffEditField           matlab.ui.control.NumericEditField
        ExistCuttoffEditFieldLabel      matlab.ui.control.Label
        NicheSVMLabel                   matlab.ui.control.Label
        PreprocessingPanel              matlab.ui.container.Panel
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        DEGAnalysisButton               matlab.ui.control.Button
        DataPreprocessingButton         matlab.ui.control.Button
        notselectedyetLabel_2           matlab.ui.control.Label
        filenameLabel_2                 matlab.ui.control.Label
        notselectedyetLabel             matlab.ui.control.Label
        filenameLabel                   matlab.ui.control.Label
        SelectButton_2                  matlab.ui.control.Button
        STDataLabel                     matlab.ui.control.Label
        SelectButton                    matlab.ui.control.Button
        scRNADataLabel                  matlab.ui.control.Label
        newpathLabel                    matlab.ui.control.Label
        currentpathLabel                matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            input_path = app.PathEditField.Value;
            app.newpathLabel.Text = input_path;
            if (~isdeployed)
                addpath(input_path)
                addpath(genpath(pwd))
            end

            global g_input_path
            g_input_path = input_path;

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            global g_input_path
            global g_rna_filename

            path_to_go = strcat(g_input_path,'\*.*');
            [rna_file,rna_path] = uigetfile(path_to_go, 'Single-Cell RNA-Seq data file')
            app.notselectedyetLabel.Text = rna_file;
            g_rna_filename = strcat(rna_path,rna_file);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            global g_input_path
            global g_st_filename
            
            path_to_go = strcat(g_input_path,'\*.*');
            [st_file,st_path] = uigetfile(path_to_go, 'Spatial data file')
            app.notselectedyetLabel_2.Text = st_file;
            g_st_filename = strcat(st_path,st_file);            
        end

        % Button pushed function: DataPreprocessingButton
        function DataPreprocessingButtonPushed(app, event)
            global g_rna_filename
            global g_st_filename
            global g_input_path
            
            app.StatusLamp.Color = 'red';
            pause(1)

            preprocess_1(g_rna_filename,g_st_filename,string(g_input_path));
            preprocess_2(string(g_input_path))

            app.StatusLamp.Color = 'green';

        end

        % Button pushed function: DEGAnalysisButton
        function DEGAnalysisButtonPushed(app, event)
            deg_analysis;
        end

        % Callback function
        function IntegratedDataAnalysisButtonPushed(app, event)
            niche_analysis;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.SetButton = uibutton(app.PreprocessingPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.currentpathLabel = uilabel(app.PreprocessingPanel);
            app.currentpathLabel.Text = 'current path:';
            app.newpathLabel = uilabel(app.PreprocessingPanel);
            app.newpathLabel.Text = '';
            app.scRNADataLabel = uilabel(app.PreprocessingPanel);
            app.scRNADataLabel.Text = 'scRNA Data:';
            app.SelectButton = uibutton(app.PreprocessingPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.STDataLabel = uilabel(app.PreprocessingPanel);
            app.STDataLabel.Text = 'ST Data:';
            app.SelectButton_2 = uibutton(app.PreprocessingPanel, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.filenameLabel = uilabel(app.PreprocessingPanel);
            app.filenameLabel.Text = 'filename: ';
            app.notselectedyetLabel = uilabel(app.PreprocessingPanel);
            app.notselectedyetLabel.Text = 'not selected yet';
            app.filenameLabel_2 = uilabel(app.PreprocessingPanel);
            app.filenameLabel_2.Text = 'filename:';
            app.notselectedyetLabel_2 = uilabel(app.PreprocessingPanel);
            app.notselectedyetLabel_2.Text = 'not selected yet';
            app.DataPreprocessingButton = uibutton(app.PreprocessingPanel, 'push');
            app.DataPreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @DataPreprocessingButtonPushed, true);
            app.DataPreprocessingButton.Text = 'Data Preprocessing';
            app.DEGAnalysisButton = uibutton(app.PreprocessingPanel, 'push');
            app.DEGAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DEGAnalysisButtonPushed, true);
            app.DEGAnalysisButton.Text = 'DEG Analysis';
            app.PathEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.PathEditFieldLabel.Text = 'Path: ';
            app.PathEditField = uieditfield(app.PreprocessingPanel, 'text');
            app.PathEditField.Value = 'Please enter the desire path here';
            app.NicheSVMLabel = uilabel(app.UIFigure);
            app.NicheSVMLabel.Text = 'NicheSVM';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.ExistCuttoffEditFieldLabel = uilabel(app.AnalysisPanel);
            app.ExistCuttoffEditFieldLabel.Text = 'Exist Cuttoff';
            app.ExistCuttoffEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.ExistCuttoffEditField.Value = 0.01;
            app.pCutoffEditFieldLabel = uilabel(app.AnalysisPanel);
            app.pCutoffEditFieldLabel.Text = 'p Cutoff';
            app.pCutoffEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.pCutoffEditField.Value = 0.01;
            app.lrCuttoffEditFieldLabel = uilabel(app.AnalysisPanel);
            app.lrCuttoffEditFieldLabel.Text = 'lr Cuttoff';
            app.lrCuttoffEditField = uieditfield(app.AnalysisPanel, 'numeric');
            app.lrCuttoffEditField.Value = 0.4;
            app.ClustersEditFieldLabel = uilabel(app.AnalysisPanel);
            app.ClustersEditFieldLabel.Text = 'Clusters';
            app.ClustersEditField = uieditfield(app.AnalysisPanel, 'text');
            app.ClustersEditField.Value = '1,2,3,4';
            app.Label = uilabel(app.AnalysisPanel);
            app.Label.Text = 'Please specify the cluster no in which you are intrested sperate by comma (example 1,2,3)';
            app.StartAnalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.AdavancevisualanalysisPanel = uipanel(app.UIFigure);
            app.AdavancevisualanalysisPanel.Title = 'Adavance visual analysis';
            app.PathEditField_2Label = uilabel(app.AdavancevisualanalysisPanel);
            app.PathEditField_2Label.Text = 'Path:';
            app.PathEditField_2 = uieditfield(app.AdavancevisualanalysisPanel, 'text');
            app.PathEditField_2.Value = 'Please enter the desire path here';
            app.SetButton_2 = uibutton(app.AdavancevisualanalysisPanel, 'push');
            app.SetButton_2.Text = 'Set';
            app.NeighborspecificgeneplotButton = uibutton(app.AdavancevisualanalysisPanel, 'push');
            app.NeighborspecificgeneplotButton.Icon = fullfile(pathToMLAPP, 'resources', 'niche.png');
            app.NeighborspecificgeneplotButton.Text = 'Neighbor specific gene plot';
            app.DEGaverageexpressionplotButton = uibutton(app.AdavancevisualanalysisPanel, 'push');
            app.DEGaverageexpressionplotButton.Icon = fullfile(pathToMLAPP, 'resources', 'cell.png');
            app.DEGaverageexpressionplotButton.Text = 'DEG average expression plot';
            app.DEGcelltypeexpressionPlotButton = uibutton(app.AdavancevisualanalysisPanel, 'push');
            app.DEGcelltypeexpressionPlotButton.Icon = fullfile(pathToMLAPP, 'resources', 'comb.png');
            app.DEGcelltypeexpressionPlotButton.Text = 'DEG cell type expression Plot';
            app.CurrentpathLabel = uilabel(app.AdavancevisualanalysisPanel);
            app.CurrentpathLabel.Text = 'Current path:';
            app.cpathLabel = uilabel(app.AdavancevisualanalysisPanel);
            app.cpathLabel.Text = 'No folder selected';
            app.SpotsizeEditFieldLabel = uilabel(app.AdavancevisualanalysisPanel);
            app.SpotsizeEditFieldLabel.Text = 'Spot size:';
            app.SpotsizeEditField = uieditfield(app.AdavancevisualanalysisPanel, 'numeric');
            app.StatusLamp_2Label = uilabel(app.UIFigure);
            app.StatusLamp_2Label.Text = 'Status:';
            app.StatusLamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NicheSVM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end